classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TradeButton                    matlab.ui.control.Button
        TrilliumLabel                  matlab.ui.control.Label
        Copyright2019YourFaceIncLabel  matlab.ui.control.Label
        PauseTrading                   matlab.ui.control.Button
        ResumeButton                   matlab.ui.control.Button
        CloseAllPositionsButton        matlab.ui.control.Button
        CompanyDropDownLabel           matlab.ui.control.Label
        CompanyDropDown                matlab.ui.control.DropDown
        QuantityDropDownLabel          matlab.ui.control.Label
        QuantityDropDown               matlab.ui.control.DropDown
        AccountNumberLabel             matlab.ui.control.Label
        AccountNumberDropDown          matlab.ui.control.DropDown
        MovingAverage1DropDownLabel    matlab.ui.control.Label
        MovingAverage1DropDown         matlab.ui.control.DropDown
        MovingAverage2DropDownLabel    matlab.ui.control.Label
        MovingAverage2DropDown         matlab.ui.control.DropDown
        TimeFrameDropDownLabel         matlab.ui.control.Label
        TimeFrameDropDown              matlab.ui.control.DropDown
        UITable                        matlab.ui.control.Table
        StockEditFieldLabel            matlab.ui.control.Label
        StockEditField                 matlab.ui.control.EditField
    end

    
    properties (Access = public)
        params % Description
    end
    
    properties (Access = public)
        allTrades = []; % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %WHEN DEBUGGING, COMMENT OUT THIS CODE
%             setGlobalT([]);
%             %This creates a timer object that calls a function at a fixed rate specified by the period
%             t = timer('StartDelay', 0, 'Period', 60, 'ExecutionMode', 'fixedRate','TimerFcn',@myFun);
%             start(t);
%             
%             %This is the function
%             function myFun(obj, event)
%                 %This is passing the newest version of the allTrades object to the AWS function.
%                 app.allTrades = AWS(app.allTrades);
%             end
            %WHEN DEBUGGING UNCOMMENT THIS CODE
              while 1 ~= 0
                  pause(30);
                  app.allTrades = AWS(app.allTrades);
              end
        end

        % Button pushed function: TradeButton
        function TradeButtonPushed(app, event)
            %This is loading all the relevant trade data  from the gui into a parameters object
            app.params.Action = 'buy';
            app.params.Symbol = app.StockEditField.Value;
            app.params.Quantity = str2double(app.QuantityDropDown.Value);
            app.params.Type = 'MKT';
            app.params.AccountName = app.AccountNumberDropDown.Value;
                        
            %Creates new trade stucture with with the trades parameters and the moving average that has been selected
            newTrade = trade;
            newTrade.paramas = app.params;
            newTrade.movingAvg1 = app.MovingAverage1DropDown.Value;
            newTrade.movingAvg2 = app.MovingAverage2DropDown.Value;
            newTrade.barSize = app.TimeFrameDropDown.Value;
            newTrade.done = 0;
            newTrade.openPos = 0;
            newTrade.hold= -1;
            
            %Adds the new trade structure to the alltrades vector
            app.allTrades = [app.allTrades, newTrade];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %deletes all timer object and closes app
            tms = timerfindall;
            delete(tms);
            delete(app)
            
        end

        % Callback function
        function SellButtonPushed(app, event)
                    
           
        end

        % Button pushed function: PauseTrading
        function PauseTradingButtonPushed(app, event)
             %deletes all timer object and closes app
            tms = timerfindall;
            delete(tms);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
             %WHEN DEBUGGING, COMMENT OUT THIS CODE
            setGlobalT([]);
            %This creates a timer object that calls a function at a fixed rate specified by the period
            t = timer('StartDelay', 0, 'Period', 30, 'ExecutionMode', 'fixedRate','TimerFcn',@myFun);
            start(t);
            
            %This is the function
            function myFun(obj, event)
                %This is passing the newest version of the allTrades object to the AWS function.
                app.allTrades = AWS(app.allTrades);
            end
        end

        % Button pushed function: CloseAllPositionsButton
        function CloseAllPositionsButtonPushed(app, event)
            CloseAll(app.allTrades);
            app.allTrades = [];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            AccountName = app.params.AccountName;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TradeButton = uibutton(app.UIFigure, 'push');
            app.TradeButton.ButtonPushedFcn = createCallbackFcn(app, @TradeButtonPushed, true);
            app.TradeButton.Text = 'Trade';
            app.TrilliumLabel = uilabel(app.UIFigure);
            app.TrilliumLabel.Text = 'Trillium ';
            app.Copyright2019YourFaceIncLabel = uilabel(app.UIFigure);
            app.Copyright2019YourFaceIncLabel.Text = 'Copyright 2019. Your Face Inc.';
            app.PauseTrading = uibutton(app.UIFigure, 'push');
            app.PauseTrading.ButtonPushedFcn = createCallbackFcn(app, @PauseTradingButtonPushed, true);
            app.PauseTrading.Text = 'Pause Trading';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume Button';
            app.CloseAllPositionsButton = uibutton(app.UIFigure, 'push');
            app.CloseAllPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllPositionsButtonPushed, true);
            app.CloseAllPositionsButton.Text = 'Close All Positions';
            app.CompanyDropDownLabel = uilabel(app.UIFigure);
            app.CompanyDropDownLabel.Text = 'Company';
            app.CompanyDropDown = uidropdown(app.UIFigure);
            app.CompanyDropDown.Items = {'TSLA', 'NFLX', 'AAPL', 'QQQ', 'SQQQ', 'C'};
            app.CompanyDropDown.Value = 'TSLA';
            app.QuantityDropDownLabel = uilabel(app.UIFigure);
            app.QuantityDropDownLabel.Text = 'Quantity';
            app.QuantityDropDown = uidropdown(app.UIFigure);
            app.QuantityDropDown.Items = {'50', '100', '150', '200', '250', '300', '350', '400', '450', '500'};
            app.QuantityDropDown.Value = '50';
            app.AccountNumberLabel = uilabel(app.UIFigure);
            app.AccountNumberLabel.Text = 'Account Number';
            app.AccountNumberDropDown = uidropdown(app.UIFigure);
            app.AccountNumberDropDown.Items = {'DU1226940', 'DU1226941', 'DU1226943', 'DU1226944', 'DU1226945', 'U1670223', 'U2229103', 'U1611478'};
            app.AccountNumberDropDown.Value = 'DU1226940';
            app.MovingAverage1DropDownLabel = uilabel(app.UIFigure);
            app.MovingAverage1DropDownLabel.Text = 'Moving Average 1';
            app.MovingAverage1DropDown = uidropdown(app.UIFigure);
            app.MovingAverage1DropDown.Items = {'3 MA', '4 MA', '5 MA', '7 MA', '8 MA', '10 MA', '15 MA'};
            app.MovingAverage1DropDown.Value = '3 MA';
            app.MovingAverage2DropDownLabel = uilabel(app.UIFigure);
            app.MovingAverage2DropDownLabel.Text = 'Moving Average 2';
            app.MovingAverage2DropDown = uidropdown(app.UIFigure);
            app.MovingAverage2DropDown.Items = {'3 MA', '4 MA', '5 MA', '7 MA', '8 MA', '10 MA', '15 MA'};
            app.MovingAverage2DropDown.Value = '3 MA';
            app.TimeFrameDropDownLabel = uilabel(app.UIFigure);
            app.TimeFrameDropDownLabel.Text = 'Time Frame';
            app.TimeFrameDropDown = uidropdown(app.UIFigure);
            app.TimeFrameDropDown.Items = {'1 min', '3 mins', '5 mins'};
            app.TimeFrameDropDown.Value = '1 min';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Test1'; 'Test2'; 'Column 3'; 'Column 4'; ''};
            app.UITable.RowName = {'Stock1'; 'Stock2'};
            app.UITable.ColumnEditable = [true true true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.StockEditFieldLabel = uilabel(app.UIFigure);
            app.StockEditFieldLabel.Text = 'Stock';
            app.StockEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end